classdef IconsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IconsAppUIFigure    matlab.ui.Figure
        Axes                matlab.ui.control.UIAxes
        IconsLabel          matlab.ui.control.Label
        Icons               matlab.ui.control.ListBox
        SearchLabel         matlab.ui.control.Label
        Search              matlab.ui.control.EditField
        ColorDropDownLabel  matlab.ui.control.Label
        Color               matlab.ui.control.DropDown
        Pallette            matlab.ui.control.Button
        ScaleLabel          matlab.ui.control.Label
        Scale               matlab.ui.control.Slider
        Colors              matlab.ui.control.Button
        Save                matlab.ui.control.Button
        Update              matlab.ui.control.Button
        Updatelabel         matlab.ui.control.Label
        Save_2              matlab.ui.control.Button
        PacksPanel          matlab.ui.container.Panel
        Name                matlab.ui.control.Label
        OpenLibrary         matlab.ui.control.Button
        Num                 matlab.ui.control.Label
        AddFav              matlab.ui.control.Button
        Clear               matlab.ui.control.Button
        OpenIcon            matlab.ui.control.Button
    end

    
    properties (Access = private)
        I % Icons object
        Packs
        U % Updater class
    end
    
    methods (Access = private)
        
        function DrawPacks(app)
            [~, packs] = app.I.getPacks();
            if ~isempty(packs)
                pos = app.PacksPanel.Position;
                N = length(packs);
                ys = pos(4) - (1 : N) * 22 - 25;
                if ys(end) < 0
                    ys = ys - ys(end);
                end
                for i = 1 : N
                    app.Packs(i) = uicheckbox(app.PacksPanel, 'Position', [10 ys(i) 170 22],...
                        'Text', packs(i), 'Value', true, 'ValueChangedFcn', @app.PacksValueChanged);
                end
            end
        end
        
        function PacksValueChanged(app, obj, event)
            packs = get(app.Packs, 'Text');
            vals = get(app.Packs, 'Value');
            vals = [vals{:}]';
            packs = packs(vals);
            app.I.usePacks(packs);
            icons = app.I.getIcons();
            app.Icons.Items = icons.Name;
            app.UpdateNum();
            app.SearchValueChanging();
            app.IconsValueChanged();
        end
        
        function UpdatePlot(app)
            app.I.show(app.Axes);
            app.Name.Text = app.I.Name;
            size = app.I.getSize();
            pos = app.Axes.Position;
            app.Axes.Position = [pos(1), pos(2)+pos(4)-size(1), size(2), size(1)];
        end
        
        
        function UpdateNum(app)
            app.Num.Text = "(" + length(app.Icons.Items) + ")";
        end
        
        function ShowUpdate(app, isupd)
            % Show update button
            if isupd
                app.Update.Visible = 'on';
                app.Updatelabel.Visible = 'on';
            end
        end
        
        function sel = ConfirmUpdate(app)
            % Update confirmation
            msg = sprintf("Update to the version %s?", app.U.vr);
            sum = app.U.getrelsum();
            if sum ~= ""
                msg = msg + sprintf("\nRelease summary:\n%s", sum);
            end
            sel = uiconfirm(app.IconsAppUIFigure, msg, 'Confirm update',...
                'Options', ["Update","What's new?","Cancel"], 'DefaultOption', 'Update', 'CancelOption', 'Cancel');
        end
        
        function wb = waitbar(app, title)
            % Wait bar
            wb = uiprogressdlg(app.IconsAppUIFigure, 'Title', title, 'Indeterminate', 'on');
        end
        
        function alert(app, title, msg)
            uialert(app.IconsAppUIFigure, msg, title, 'Icon', 'success');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Axes.Toolbar = [];
            app.Name.Text = '';
            app.Update.Visible = 'off';
            app.Updatelabel.Visible = 'off';
            app.I = Icon();
            colors = app.I.getColors();
            app.Color.Items = [{''}; colors.Name];
            app.DrawPacks();
            app.PacksValueChanged();
            app.U = IconsUpdater();
            app.U.isupdate(@app.ShowUpdate, 3);
        end

        % Value changed function: Icons
        function IconsValueChanged(app, event)
            name = app.Icons.Value;
            if ~isempty(name)
                app.I.use(name);
                app.UpdatePlot();
            else
                title(app.Axes, '');
                app.Axes.cla;
            end
        end

        % Value changing function: Search
        function SearchValueChanging(app, event)
            if nargin < 2
                value = app.Search.Value;
            else
                value = event.Value;
            end
            icons = app.I.search(value);
            app.Icons.Items = icons.Name;
            app.UpdateNum();
            app.IconsValueChanged();
        end

        % Button pushed function: Pallette
        function PalletteButtonPushed(app, event)
            [~, color] = app.I.pickColor();
            figure(app.IconsAppUIFigure);
            if length(color) == 3
                color = "[" + num2str(color) + "]";
                cs = app.Color.Items;
                if ~ismember(cs, color)
                    app.Color.Items = [{char(color)} cs];
                end
                app.Color.Value = color;
                app.IconsValueChanged();
            end
        end

        % Button pushed function: Colors
        function ColorsButtonPushed(app, event)
            app.I.showColors();
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            app.I.save()
        end

        % Value changed function: Scale
        function ScaleValueChanged(app, event)
            value = app.Scale.Value;
            app.I.setScale(value);
            app.UpdatePlot();
        end

        % Value changed function: Color
        function ColorValueChanged(app, event)
            value = app.Color.Value;
            app.I.setColor(value);
            app.UpdatePlot();
        end

        % Button pushed function: OpenLibrary
        function OpenLibraryButtonPushed(app, event)
            app.I.lib();
        end

        % Button pushed function: Save_2
        function Save_2ButtonPushed(app, event)
            Icon.doc();
        end

        % Button pushed function: Update
        function UpdateButtonPushed(app, event)
            sel = app.ConfirmUpdate();
            switch sel
                case "Update"
                    wb = app.waitbar('Update in prpogress');
                    app.U.update(2, @() delete(app), @IconsApp);
                case "What's new?"
                    app.U.webrel();
            end
        end

        % Callback function
        function AddFavButtonPushed(app, event)
            app.U.ext.addfav('Icons App', 'IconsApp', 'icons/MyIcons/my-icons.png')
        end

        % Button pushed function: AddFav
        function AddFavButtonPushed2(app, event)
            app.U.ext.addfav('Icons App', 'IconsApp', 'icons/icon-icons-toolbox.png');
            app.alert('Success', 'IconsApp shortcut was added to MATLAB Favorites and Quick Access Toolbar')
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            app.Search.Value = '';
            app.SearchValueChanging();
        end

        % Button pushed function: OpenIcon
        function OpenIconPushed(app, event)
            app.I.open();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IconsAppUIFigure = uifigure('Visible', 'off');
            app.IconsAppUIFigure.Name = 'Icons App';
            app.Axes = uiaxes(app.IconsAppUIFigure);
            title(app.Axes, '')
            xlabel(app.Axes, '')
            ylabel(app.Axes, '')
            app.IconsLabel = uilabel(app.IconsAppUIFigure);
            app.IconsLabel.Text = 'Icons';
            app.Icons = uilistbox(app.IconsAppUIFigure);
            app.Icons.ValueChangedFcn = createCallbackFcn(app, @IconsValueChanged, true);
            app.SearchLabel = uilabel(app.IconsAppUIFigure);
            app.SearchLabel.Text = 'Search';
            app.Search = uieditfield(app.IconsAppUIFigure, 'text');
            app.Search.ValueChangingFcn = createCallbackFcn(app, @SearchValueChanging, true);
            app.ColorDropDownLabel = uilabel(app.IconsAppUIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.Color = uidropdown(app.IconsAppUIFigure);
            app.Color.Editable = 'on';
            app.Color.ValueChangedFcn = createCallbackFcn(app, @ColorValueChanged, true);
            app.Pallette = uibutton(app.IconsAppUIFigure, 'push');
            app.Pallette.ButtonPushedFcn = createCallbackFcn(app, @PalletteButtonPushed, true);
            app.Pallette.Icon = 'icon-mat-palette.png';
            app.Pallette.Tooltip = {'Pick color'};
            app.Pallette.Text = '';
            app.ScaleLabel = uilabel(app.IconsAppUIFigure);
            app.ScaleLabel.Text = 'Scale';
            app.Scale = uislider(app.IconsAppUIFigure);
            app.Scale.Limits = [0.2 1];
            app.Scale.MajorTicks = [0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Scale.MajorTickLabels = {'0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.Scale.ValueChangedFcn = createCallbackFcn(app, @ScaleValueChanged, true);
            app.Scale.MinorTicks = [0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Scale.Value = 1;
            app.Colors = uibutton(app.IconsAppUIFigure, 'push');
            app.Colors.ButtonPushedFcn = createCallbackFcn(app, @ColorsButtonPushed, true);
            app.Colors.Icon = 'icon-fa-list.png';
            app.Colors.Tooltip = {'List of colors'};
            app.Colors.Text = '';
            app.Save = uibutton(app.IconsAppUIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Icon = 'icon-fa-save.png';
            app.Save.Tooltip = {'Save to current folder'};
            app.Save.Text = 'Save';
            app.Update = uibutton(app.IconsAppUIFigure, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.Update.Icon = 'icon-my-update-daa520.png';
            app.Update.Text = 'Update';
            app.Updatelabel = uilabel(app.IconsAppUIFigure);
            app.Updatelabel.Text = 'New version is available';
            app.Save_2 = uibutton(app.IconsAppUIFigure, 'push');
            app.Save_2.ButtonPushedFcn = createCallbackFcn(app, @Save_2ButtonPushed, true);
            app.Save_2.Icon = 'icon-mat-help-outline.png';
            app.Save_2.Tooltip = {'Help'};
            app.Save_2.Text = '';
            app.PacksPanel = uipanel(app.IconsAppUIFigure);
            app.PacksPanel.Title = 'Icon Packs';
            app.Name = uilabel(app.IconsAppUIFigure);
            app.Name.Text = 'Name';
            app.OpenLibrary = uibutton(app.IconsAppUIFigure, 'push');
            app.OpenLibrary.ButtonPushedFcn = createCallbackFcn(app, @OpenLibraryButtonPushed, true);
            app.OpenLibrary.Text = 'Open Library';
            app.Num = uilabel(app.IconsAppUIFigure);
            app.Num.Text = '(0)';
            app.AddFav = uibutton(app.IconsAppUIFigure, 'push');
            app.AddFav.ButtonPushedFcn = createCallbackFcn(app, @AddFavButtonPushed2, true);
            app.AddFav.Icon = 'icon-fa-star-o.png';
            app.AddFav.Tooltip = {'Create App shortcut'};
            app.AddFav.Text = '';
            app.Clear = uibutton(app.IconsAppUIFigure, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'X';
            app.OpenIcon = uibutton(app.IconsAppUIFigure, 'push');
            app.OpenIcon.ButtonPushedFcn = createCallbackFcn(app, @OpenIconPushed, true);
            app.OpenIcon.Icon = 'icon-mat-open-in-new.png';
            app.OpenIcon.Tooltip = {'Open icon'};
            app.OpenIcon.Text = 'Open';
            % Show the figure after all components are created
            app.IconsAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IconsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IconsAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IconsAppUIFigure)
        end
    end
end