classdef faApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FontAwesomeAppUIFigure      matlab.ui.Figure
        Axes                        matlab.ui.control.UIAxes
        IconsLabel                  matlab.ui.control.Label
        Icons                       matlab.ui.control.ListBox
        SearchLabel                 matlab.ui.control.Label
        Search                      matlab.ui.control.EditField
        ColorDropDownLabel          matlab.ui.control.Label
        Color                       matlab.ui.control.DropDown
        Pallette                    matlab.ui.control.Button
        SizeSliderLabel             matlab.ui.control.Label
        Size                        matlab.ui.control.Slider
        Colors                      matlab.ui.control.Button
        Save                        matlab.ui.control.Button
        UpdateButton                matlab.ui.control.Button
        NewversionisavailableLabel  matlab.ui.control.Label
        Save_2                      matlab.ui.control.Button
        PacksPanel                  matlab.ui.container.Panel
    end

    
    properties (Access = private)
        I % Icons object
        Packs
    end
    
    methods (Access = private)
        
        function DrawPacks(app)
            packs = app.I.allpacks;
            if ~isempty(packs)
                pos = app.PacksPanel.Position;
                N = length(packs);
                ys = pos(4) - (1 : N) * 22 - 25;
                if ys(end) < 0
                    ys = ys - ys(end);
                end
                for i = 1 : N
                    app.Packs(i) = uicheckbox(app.PacksPanel, 'Position', [10 ys(i) 170 22],...
                        'Text', packs(i), 'Value', true, 'ValueChangedFcn', @app.PacksValueChanged);
                end
            end
        end
        
        function PacksValueChanged(app, obj, event)
            packs = get(app.Packs, 'Text');
            vals = get(app.Packs, 'Value');
            vals = [vals{:}]';
            packs = packs(vals);
            app.I.usepacks(packs);
            app.Icons.Items = app.I.icons.name;
            app.IconsValueChanged();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Axes.Toolbar = [];
            app.Axes.XTick = [];
            app.Axes.YTick = [];
            app.I = Icons();
            colors = app.I.colors;
            app.Color.Items = [{''}; colors.name];
            app.DrawPacks();
            app.PacksValueChanged();
            
        end

        % Value changed function: Color, Icons, Size
        function IconsValueChanged(app, event)
            name = app.Icons.Value;
            if ~isempty(name)
                color = app.Color.Value;
                size = app.Size.Value;
                app.I.show(name, color, size, app.Axes);
                pos = app.Axes.Position;
                app.Axes.Position = [pos(1), pos(2)+pos(4)-size, size, size];
            else
                title(app.Axes, '');
                app.Axes.cla;
            end
        end

        % Value changing function: Search
        function SearchValueChanging(app, event)
            value = event.Value;
            icons = app.I.search(value);
            app.Icons.Items = icons.name;
            app.IconsValueChanged();
        end

        % Button pushed function: Pallette
        function PalletteButtonPushed(app, event)
            color = uisetcolor();
            figure(app.FontAwesomeAppUIFigure);
            if length(color) == 3
                color = "[" + num2str(color) + "]";
                cs = app.Color.Items;
                if ~ismember(cs, color)
                    app.Color.Items = [{char(color)} cs];
                end
                app.Color.Value = color;
                app.IconsValueChanged();
            end
        end

        % Button pushed function: Colors
        function ColorsButtonPushed(app, event)
            app.I.showcolors();
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            app.I.use(app.Icons.Value, app.Size.Value, app.Color.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FontAwesomeAppUIFigure = uifigure('Visible', 'off');
            app.FontAwesomeAppUIFigure.Name = 'Font Awesome App';
            app.Axes = uiaxes(app.FontAwesomeAppUIFigure);
            title(app.Axes, '')
            xlabel(app.Axes, '')
            ylabel(app.Axes, '')
            app.IconsLabel = uilabel(app.FontAwesomeAppUIFigure);
            app.IconsLabel.Text = 'Icons';
            app.Icons = uilistbox(app.FontAwesomeAppUIFigure);
            app.Icons.ValueChangedFcn = createCallbackFcn(app, @IconsValueChanged, true);
            app.SearchLabel = uilabel(app.FontAwesomeAppUIFigure);
            app.SearchLabel.Text = 'Search';
            app.Search = uieditfield(app.FontAwesomeAppUIFigure, 'text');
            app.Search.ValueChangingFcn = createCallbackFcn(app, @SearchValueChanging, true);
            app.ColorDropDownLabel = uilabel(app.FontAwesomeAppUIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.Color = uidropdown(app.FontAwesomeAppUIFigure);
            app.Color.Editable = 'on';
            app.Color.ValueChangedFcn = createCallbackFcn(app, @IconsValueChanged, true);
            app.Pallette = uibutton(app.FontAwesomeAppUIFigure, 'push');
            app.Pallette.ButtonPushedFcn = createCallbackFcn(app, @PalletteButtonPushed, true);
            app.Pallette.Icon = 'icon-fa-paint-brush.png';
            app.Pallette.Text = '';
            app.SizeSliderLabel = uilabel(app.FontAwesomeAppUIFigure);
            app.SizeSliderLabel.Text = 'Size';
            app.Size = uislider(app.FontAwesomeAppUIFigure);
            app.Size.Limits = [64 256];
            app.Size.MajorTicks = [32 64 128 192 256 512];
            app.Size.MajorTickLabels = {'32', '64', '128', '192', '256', '512'};
            app.Size.ValueChangedFcn = createCallbackFcn(app, @IconsValueChanged, true);
            app.Size.Value = 256;
            app.Colors = uibutton(app.FontAwesomeAppUIFigure, 'push');
            app.Colors.ButtonPushedFcn = createCallbackFcn(app, @ColorsButtonPushed, true);
            app.Colors.Icon = 'icon-fa-list.png';
            app.Colors.Text = '';
            app.Save = uibutton(app.FontAwesomeAppUIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Icon = 'icon-fa-save.png';
            app.Save.Text = 'Save';
            app.UpdateButton = uibutton(app.FontAwesomeAppUIFigure, 'push');
            app.UpdateButton.Icon = 'icon-fa-arrow-circle-up.png';
            app.UpdateButton.Text = 'Update';
            app.NewversionisavailableLabel = uilabel(app.FontAwesomeAppUIFigure);
            app.NewversionisavailableLabel.Text = 'New version is available';
            app.Save_2 = uibutton(app.FontAwesomeAppUIFigure, 'push');
            app.Save_2.Icon = 'icon-fa-info.png';
            app.Save_2.Text = '';
            app.PacksPanel = uipanel(app.FontAwesomeAppUIFigure);
            app.PacksPanel.Title = 'Icon Packs';
            % Show the figure after all components are created
            app.FontAwesomeAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = faApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FontAwesomeAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FontAwesomeAppUIFigure)
        end
    end
end